classdef Lab10_110550085 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ColorDisplay            matlab.ui.control.Label
        XmaxField               matlab.ui.control.NumericEditField
        toEditFieldLabel        matlab.ui.control.Label
        XminField               matlab.ui.control.NumericEditField
        XrangeEditFieldLabel    matlab.ui.control.Label
        ColorButton             matlab.ui.control.Button
        MarkerTypeGroup         matlab.ui.container.ButtonGroup
        XxButton                matlab.ui.control.RadioButton
        plusButton              matlab.ui.control.RadioButton
        circleoButton           matlab.ui.control.RadioButton
        NoneButton              matlab.ui.control.RadioButton
        LineStyleDropdown       matlab.ui.control.DropDown
        LineTypeLabel           matlab.ui.control.Label
        FunctionEdit            matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
        ax                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
            function plotFunction(app)
                try
                    s = app.FunctionEdit.Value;
                    fh = str2func(['@(x) ' s]);
                    x1 = app.XminField.Value;
                    x2 = app.XmaxField.Value;
            
                    if (x1>=x2)
                        uialert(app.UIFigure, 'X-Min must be less than X-max.', 'Invalid X Range');
                        return;
                    end
            
                    fp = fplot(app.ax, fh, [x1 x2]);
                    set(fp, 'LineStyle', app.LineStyleDropdown.Value);
            
                    selectedMarker = app.MarkerTypeGroup.SelectedObject.Text;
                    if strcmp(selectedMarker, 'None')
                        set(fp, 'Marker', 'none');
                    elseif strcmp(selectedMarker, 'X (x)')
                        set(fp, 'Marker', 'x');
                    elseif strcmp(selectedMarker, 'plus (+)')
                        set(fp, 'Marker', '+');
                    elseif strcmp(selectedMarker, 'circle (o)')
                        set(fp, 'Marker', 'o');   
                    end
            
                    set(fp, 'Color', app.ColorDisplay.BackgroundColor);
        
                catch ME
                    uialert(app.UIFigure, ME.message, 'Error');
                end
            end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XmaxField.Value = 10;
            app.XminField.Value = -5;
            defaultColor = [0 0 0]; 
            app.ColorDisplay.BackgroundColor = defaultColor;
            %fh = str2func(['@(x) ' sin(x)]);
            app.FunctionEdit.Value = 'sin(x)';
            plotFunction(app);
        end

        % Value changed function: FunctionEdit
        function FunctionEditValueChanged(app, event)
            plotFunction(app);
            
        end

        % Value changed function: LineStyleDropdown
        function LineStyleDropdownValueChanged(app, event)
            plotFunction(app);
            
        end

        % Selection changed function: MarkerTypeGroup
        function MarkerTypeGroupSelectionChanged(app, event)
            plotFunction(app);
            
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            c = uisetcolor();
            app.ColorDisplay.BackgroundColor = c;
            plotFunction(app);
        end

        % Value changed function: XminField
        function XminFieldValueChanged(app, event)
            plotFunction(app);
            
        end

        % Value changed function: XmaxField
        function XmaxFieldValueChanged(app, event)
            plotFunction(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax = uiaxes(app.UIFigure);
            title(app.ax, 'Ax')
            xlabel(app.ax, 'X')
            ylabel(app.ax, 'Y')
            zlabel(app.ax, 'Z')
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function: ';
            app.FunctionEdit = uieditfield(app.UIFigure, 'text');
            app.FunctionEdit.ValueChangedFcn = createCallbackFcn(app, @FunctionEditValueChanged, true);
            app.LineTypeLabel = uilabel(app.UIFigure);
            app.LineTypeLabel.Text = 'Line Type:';
            app.LineStyleDropdown = uidropdown(app.UIFigure);
            app.LineStyleDropdown.Items = {'-', '--', ':', '-.'};
            app.LineStyleDropdown.ValueChangedFcn = createCallbackFcn(app, @LineStyleDropdownValueChanged, true);
            app.LineStyleDropdown.Value = '-';
            app.MarkerTypeGroup = uibuttongroup(app.UIFigure);
            app.MarkerTypeGroup.SelectionChangedFcn = createCallbackFcn(app, @MarkerTypeGroupSelectionChanged, true);
            app.MarkerTypeGroup.Title = 'Symbol Type';
            app.NoneButton = uiradiobutton(app.MarkerTypeGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.circleoButton = uiradiobutton(app.MarkerTypeGroup);
            app.circleoButton.Text = 'circle (o)';
            app.plusButton = uiradiobutton(app.MarkerTypeGroup);
            app.plusButton.Text = 'plus (+)';
            app.XxButton = uiradiobutton(app.MarkerTypeGroup);
            app.XxButton.Text = 'X (x)';
            app.ColorButton = uibutton(app.UIFigure, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.XrangeEditFieldLabel = uilabel(app.UIFigure);
            app.XrangeEditFieldLabel.Text = 'X range:';
            app.XminField = uieditfield(app.UIFigure, 'numeric');
            app.XminField.ValueChangedFcn = createCallbackFcn(app, @XminFieldValueChanged, true);
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.XmaxField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxField.ValueChangedFcn = createCallbackFcn(app, @XmaxFieldValueChanged, true);
            app.ColorDisplay = uilabel(app.UIFigure);
            app.ColorDisplay.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab10_110550085

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end