classdef Lab08_110550085 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectFolderButton  matlab.ui.control.Button
        ImageInfoLabel      matlab.ui.control.Label
        PathLabel           matlab.ui.control.Label
        ListBox             matlab.ui.control.ListBox
        ListBoxLabel        matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder % Selected folder(complete path)
        im0
    end
    
    methods (Access = private)
        
        function select_file(app, fileName)
            filePath = fullfile(app.folder, fileName);
            
            if ~isfile(filePath)
                disp('Path not found');
                return;
            end
            try 
                app.im0 = imread(filePath);
                image(app.UIAxes, app.im0);

                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.XLabel.String = '';
                app.UIAxes.YLabel.String = '';

                [rows, cols, channels] = size(app.im0);
                app.ImageInfoLabel.Text = sprintf('Size:%dx%dx%d', rows, cols, channels);
            catch ME
                disp('Error reading or displaying the image:');
                disp(ME.message);
                
                app.ImageInfoLabel.Text = 'Error to display the image';
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            folderPath = uigetdir;

            if folderPath == 0
                return;
            end

            app.folder = folderPath;
            app.PathLabel.Text = folderPath;

            imageExtensions = {'*.jpg', '*.jpeg', '*.png', '*.bmp', '*.tiff', '*.gif'};
            imageFiles = [];
            for i = 1:length(imageExtensions)
                imageFiles = [imageFiles;  dir(fullfile(folderPath, imageExtensions{i}))];
            end
           
            imageNames = {imageFiles.name};

            app.ListBox.Items = imageNames;

            if ~isempty(imageNames)
                app.select_file(imageNames{1});
            end

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            selectedImage = app.ListBox.Value;
            app.select_file(selectedImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'PathLabel';
            app.ImageInfoLabel = uilabel(app.UIFigure);
            app.ImageInfoLabel.Text = 'ImageInfo';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab08_110550085

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end