classdef Lab09_110550085 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ScrollablePanel     matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        ResetButton         matlab.ui.control.Button
        GammaSliderBLabel   matlab.ui.control.Label
        GammaSliderB        matlab.ui.control.Slider
        GammaSliderG        matlab.ui.control.Slider
        GammaSliderGLabel   matlab.ui.control.Label
        GammaSliderR        matlab.ui.control.Slider
        GammaSliderRLabel   matlab.ui.control.Label
        SelectFolderButton  matlab.ui.control.Button
        ImageInfoLabel      matlab.ui.control.Label
        PathLabel           matlab.ui.control.Label
        ListBox             matlab.ui.control.ListBox
        ListBoxLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        folder % Selected folder(complete path)
        im0
        Name
    end
    
    methods (Access = private)
        
        % Function to load the file and read the image
        function select_file(app, fileName)
            filePath = fullfile(app.folder, fileName);
            app.Name = fileName;

            if ~isfile(filePath)
                disp('Path not found');
                return;
            end

            try
                app.im0 = im2double(imread(filePath)); % Store the original image
                show_image(app, 1, 1, 1); % Call show_image to display the image
                
                app.GammaSliderR.Value = 1;
                app.GammaSliderG.Value = 1;
                app.GammaSliderB.Value = 1;
                
                app.GammaSliderR.Enable = 'on';
                app.GammaSliderG.Enable = 'on';
                app.GammaSliderB.Enable = 'on';
                app.ResetButton.Enable = 'on';
            catch ME
                disp('Error reading or displaying the image:');
                disp(ME.message);
                app.ImageInfoLabel.Text = 'Error to display the image';
            end
        end

        % Function to display the image
        function show_image(app, rR, rG, rB)
            if isempty(app.im0)
                return; % If no image is loaded, exit the function
            end
            if nargin < 4
                rB = 1;
            end
            if nargin < 3
                rG = 1;
            end
            if nargin < 2
                rR = 1;
            end
            im = app.im0;
            im(:, :, 1) = im(:, :, 1) .^rR;
            im(:, :, 2) = im(:, :, 2) .^rG;
            im(:, :, 3) = im(:, :, 3) .^rB;
            [h, w, ~] = size(im);
            app.UIAxes.Position = [0 0 w h];
            image(app.UIAxes, im); % Display the image on the axes
        
            % Hide axes ticks and labels
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';

            % Update image information label
            [rows, cols, channels] = size(app.im0);
            app.ImageInfoLabel.Text = sprintf('Image Info: %s(%dx%dx%d)', app.Name, rows, cols, channels);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GammaSliderR.Enable = 'off';
            app.GammaSliderG.Enable = 'off';
            app.GammaSliderB.Enable = 'off';
            
            app.ResetButton.Enable = 'off';
            
            app.GammaSliderR.Value = 1;
            app.GammaSliderG.Value = 1;
            app.GammaSliderB.Value = 1;
            
            app.ImageInfoLabel.Text = 'No image loaded';
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            folderPath = uigetdir;

            if folderPath == 0
                return;
            end

            app.folder = folderPath;
            app.PathLabel.Text = ['Selected Path: ', folderPath];

            imageExtensions = {'*.jpg', '*.jpeg', '*.png', '*.bmp', '*.tiff', '*.gif'};
            imageFiles = [];
            for i = 1:length(imageExtensions)
                imageFiles = [imageFiles;  dir(fullfile(folderPath, imageExtensions{i}))];
            end
           
            imageNames = {imageFiles.name};

            app.ListBox.Items = imageNames;

            if ~isempty(imageNames)
                app.select_file(imageNames{1});
            end

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            selectedImage = app.ListBox.Value;
            app.select_file(selectedImage);
        end

        % Value changed function: GammaSliderR
        function GammaSliderRValueChanged(app, event)
            rR = 2 - app.GammaSliderR.Value;
            rG = 2 - app.GammaSliderG.Value;
            rB = 2 - app.GammaSliderB.Value;
            app.show_image(rR, rG, rB);
        end

        % Value changed function: GammaSliderG
        function GammaSliderGValueChanged(app, event)
            rR = 2 - app.GammaSliderR.Value;
            rG = 2 - app.GammaSliderG.Value;
            rB = 2 - app.GammaSliderB.Value;
            app.show_image(rR, rG, rB);
        end

        % Value changed function: GammaSliderB
        function GammaSliderBValueChanged(app, event)
            rR = 2 - app.GammaSliderR.Value;
            rG = 2 - app.GammaSliderG.Value;
            rB = 2 - app.GammaSliderB.Value;
            app.show_image(rR, rG, rB);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.GammaSliderR.Value = 1;
            app.GammaSliderG.Value = 1;
            app.GammaSliderB.Value = 1;
            app.show_image();
        end

        % Value changing function: GammaSliderR
        function GammaSliderRValueChanging(app, event)
            rR = event.Value;
            rG = app.GammaSliderG.Value;
            rB = app.GammaSliderB.Value;
            
            app.show_image(rR, rG, rB);
        end

        % Value changing function: GammaSliderG
        function GammaSliderGValueChanging(app, event)
            rR = app.GammaSliderR.Value;
            rG = event.Value;
            rB = app.GammaSliderB.Value;
            
            app.show_image(rR, rG, rB);
        end

        % Value changing function: GammaSliderB
        function GammaSliderBValueChanging(app, event)
            rR = app.GammaSliderR.Value;
            rG = app.GammaSliderG.Value;
            rB = event.Value;
            
            app.show_image(rR, rG, rB);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'PathLabel';
            app.ImageInfoLabel = uilabel(app.UIFigure);
            app.ImageInfoLabel.Text = 'ImageInfo';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.GammaSliderRLabel = uilabel(app.UIFigure);
            app.GammaSliderRLabel.Text = 'GammaSliderR';
            app.GammaSliderR = uislider(app.UIFigure);
            app.GammaSliderR.Limits = [-2 2];
            app.GammaSliderR.ValueChangedFcn = createCallbackFcn(app, @GammaSliderRValueChanged, true);
            app.GammaSliderR.ValueChangingFcn = createCallbackFcn(app, @GammaSliderRValueChanging, true);
            app.GammaSliderR.Value = 1;
            app.GammaSliderGLabel = uilabel(app.UIFigure);
            app.GammaSliderGLabel.Text = 'GammaSliderG';
            app.GammaSliderG = uislider(app.UIFigure);
            app.GammaSliderG.Limits = [-2 2];
            app.GammaSliderG.ValueChangedFcn = createCallbackFcn(app, @GammaSliderGValueChanged, true);
            app.GammaSliderG.ValueChangingFcn = createCallbackFcn(app, @GammaSliderGValueChanging, true);
            app.GammaSliderG.Value = 1;
            app.GammaSliderB = uislider(app.UIFigure);
            app.GammaSliderB.Limits = [-2 2];
            app.GammaSliderB.ValueChangedFcn = createCallbackFcn(app, @GammaSliderBValueChanged, true);
            app.GammaSliderB.ValueChangingFcn = createCallbackFcn(app, @GammaSliderBValueChanging, true);
            app.GammaSliderB.Value = 1;
            app.GammaSliderBLabel = uilabel(app.UIFigure);
            app.GammaSliderBLabel.Text = 'GammaSliderB';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ScrollablePanel = uipanel(app.UIFigure);
            app.ScrollablePanel.Title = 'ScrollablePanel';
            app.UIAxes = uiaxes(app.ScrollablePanel);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab09_110550085

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end