classdef CollageEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HeightEditField       matlab.ui.control.NumericEditField
        HeightEditFieldLabel  matlab.ui.control.Label
        WidthEditField        matlab.ui.control.NumericEditField
        WidthEditFieldLabel   matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        SaveButton            matlab.ui.control.Button
        UpdateButton          matlab.ui.control.Button
        DownButton            matlab.ui.control.Button
        UpButton              matlab.ui.control.Button
        DeleteButton          matlab.ui.control.Button
        AddButton             matlab.ui.control.Button
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        Collage               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Images = struct('Filename', {}, 'Name', {}, 'X', {}, 'Y', {}, 'Width', {}, 'Height', {}); % struct array
    end
    
    methods (Access = private)
        
        function update_output(app)
            % canvas setting
            canvasSize = [3000, 3000];
            collage = ones(canvasSize(1), canvasSize(2), 3, 'uint8') * 255; % set background to white
            
            % Draw from back
            for i = numel(app.Images):-1:1
                imgData = imread(app.Images(i).Filename);
                
                % check if grayscale
                if size(imgData, 3) == 1
                    % if yes turn to RGB
                    imgData = repmat(imgData, [1, 1, 3]);
                end
                
                imgData = imresize(imgData, [app.Images(i).Height, app.Images(i).Width]);
                
                % where to put
                xStart = max(1, app.Images(i).X + 1);
                yStart = max(1, app.Images(i).Y + 1);
                xEnd = min(xStart + app.Images(i).Width - 1, canvasSize(2));
                yEnd = min(yStart + app.Images(i).Height - 1, canvasSize(1));
                
                % fill to canvas
                collage(yStart:yEnd, xStart:xEnd, :) = imgData(1:(yEnd-yStart+1), 1:(xEnd-xStart+1), :);
            end
            
            % show collage
            imshow(collage, 'Parent', app.Collage);
        end


        function idx = getImageIndexByName(app, name)
            % get idx by file name
            
            idx = find(strcmp({app.Images.Name}, name), 1);

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image Files (*.png, *.jpg, *.jpeg)'});
            if isequal(file, 0)
                return;
            end
            
            imgData = imread(fullfile(path, file));
            
            newImage.Filename = fullfile(path, file); % store full path
            newImage.Name = file; % save only name to Name
            newImage.X = 0;
            newImage.Y = 0;
            newImage.Width = size(imgData, 2); 
            newImage.Height = size(imgData, 1); 
            
            app.Images = [app.Images; newImage]; % put into array
            
            % update the filename of listbox
            app.ListBox.Items = {app.Images.Name}; % show only filename
            update_output(app);

            app.XEditField.Value = newImage.X;
            app.YEditField.Value = newImage.Y;
            app.WidthEditField.Value = newImage.Width;
            app.HeightEditField.Value = newImage.Height;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            update_output(app);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            idx = getImageIndexByName(app, app.ListBox.Value); 
            if isempty(idx)
                return;
            end
            
            
            app.Images(idx).Height = app.HeightEditField.Value;
            update_output(app);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            idx = getImageIndexByName(app, app.ListBox.Value); 
            if isempty(idx)
                return;
            end
            
           
            app.Images(idx).Width = app.WidthEditField.Value;
            update_output(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            idx = getImageIndexByName(app, app.ListBox.Value);
            if isempty(idx)
                return;
            end
            
            
            app.Images(idx).X = app.XEditField.Value;
            update_output(app);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            idx = getImageIndexByName(app, app.ListBox.Value);
            if isempty(idx)
                return;
            end
            
            
            app.Images(idx).Y = app.YEditField.Value;
            update_output(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            selectedFileName = app.ListBox.Value;
            
            
            idx = getImageIndexByName(app, selectedFileName);
            if isempty(idx)
                return;
            end
            
            % update edit fields by img values
            app.XEditField.Value = app.Images(idx).X;
            app.YEditField.Value = app.Images(idx).Y;
            app.WidthEditField.Value = app.Images(idx).Width;
            app.HeightEditField.Value = app.Images(idx).Height;
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
            selectedFileName = app.ListBox.Value;
            idx = getImageIndexByName(app, selectedFileName);
            
            
            if isempty(idx)
                return;  
            end
            
            app.Images(idx) = [];
            
            
            if isempty(app.Images)
                app.ListBox.Items = {};  % if void clean out listbox
            else
                app.ListBox.Items = {app.Images.Name};  % use filename
            end
            
            
            update_output(app);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            
            selectedFileName = app.ListBox.Value;
            idx = getImageIndexByName(app, selectedFileName);
            
            
            if isempty(idx) || idx == 1
                return;  % if chosen the first img then cannot move up
            end
            
            % switch
            app.Images([idx-1, idx]) = app.Images([idx, idx-1]);
            
            
            app.ListBox.Items = {app.Images.Name};  % use filename
            
            
            update_output(app);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            
            selectedFileName = app.ListBox.Value;
            idx = getImageIndexByName(app, selectedFileName);
            
            
            if isempty(idx) || idx == numel(app.Images)
                return;  % if chosen the last one no move down
            end
            
            % switch
            app.Images([idx, idx+1]) = app.Images([idx+1, idx]);
            
            
            app.ListBox.Items = {app.Images.Name};  % use filename
            
            
            update_output(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % choose filename
            [file, path] = uiputfile({'*.png', 'PNG Image (*.png)'; '*.jpg', 'JPEG Image (*.jpg)'}, 'Save Collage As');
            
            
            if isequal(file, 0)
                return;  % no manipulation then return
            end
            
            % get the savepath
            savePath = fullfile(path, file);
            
            
            canvasSize = [3000, 3000];
            collage = ones(canvasSize(1), canvasSize(2), 3, 'uint8') * 255; % set to all white
            
            % draw each pic
            for i = numel(app.Images):-1:1 % draw from last
                imgData = imread(app.Images(i).Filename);
                
                % check if grayscale
                if size(imgData, 3) == 1
                    % turn to RGB
                    imgData = repmat(imgData, [1, 1, 3]);
                end
                
                % resize the img
                imgData = imresize(imgData, [app.Images(i).Height, app.Images(i).Width]);
                
                % calculate where to put
                xStart = max(1, app.Images(i).X + 1);
                yStart = max(1, app.Images(i).Y + 1);
                xEnd = min(xStart + app.Images(i).Width - 1, canvasSize(2));
                yEnd = min(yStart + app.Images(i).Height - 1, canvasSize(1));
                
                % fill to canvas
                collage(yStart:yEnd, xStart:xEnd, :) = imgData(1:(yEnd-yStart+1), 1:(xEnd-xStart+1), :);
            end
            
            % save the thing
            imwrite(collage, savePath);
            
            % show save successful message
            uialert(app.UIFigure, 'Collage saved successfully!', 'Save Successful', 'Icon', 'success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Collage = uiaxes(app.Panel);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 100;
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CollageEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end